classdef GeneradorDeSenales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GeneradordeSealesLabel  matlab.ui.control.Label
        triangular              matlab.ui.control.UIAxes
        cuadrada                matlab.ui.control.UIAxes
        dienteSierra            matlab.ui.control.UIAxes
        TipodegrficaSinusoidalDropDownLabel  matlab.ui.control.Label
        tipoSinusoidal          matlab.ui.control.DropDown
        sinusoidal              matlab.ui.control.UIAxes
        ConfiguracionesLabel    matlab.ui.control.Label
        CantidaddePeriodosEditFieldLabel  matlab.ui.control.Label
        periodos                matlab.ui.control.NumericEditField
        generar                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generar
        function generarButtonPushed(app, event)
                % entrada
                amplitud = 10;
                frecuencia = 10e3;
                resolucion = 1000;
                cantidadPeriodos = app.periodos.Value;
                
                % procesos
                tiempo = linspace(0,cantidadPeriodos/frecuencia,resolucion);
                % sinusoidal = amplitud*sin(2*pi*frecuencia*tiempo+anguloTetha);
                sinusoidal_0 = amplitud*sin(2*pi*frecuencia*tiempo+0);
                sinusoidal_90 = amplitud*sin(2*pi*frecuencia*tiempo+pi/2);
                sinusoidal_180 = amplitud*sin(2*pi*frecuencia*tiempo+pi);
                sinusoidal_270 = amplitud*sin(2*pi*frecuencia*tiempo+3*pi/2);
                
                % salidas
                tipo = app.tipoSinusoidal.Value;
                
                if (tipo=="Sen(x)")
                    plot(app.sinusoidal,tiempo,sinusoidal_0)
                    grid on
                elseif (tipo=="Cos(x)")
                    plot(app.sinusoidal,tiempo,sinusoidal_90)
                    grid on
                elseif (tipo=="-Sen(x)")
                    plot(app.sinusoidal,tiempo,sinusoidal_180)
                    grid on
                elseif (tipo=="-Cos(x)")
                    plot(app.sinusoidal,tiempo,sinusoidal_270)
                    grid on
                end
                close all
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeneradordeSealesLabel = uilabel(app.UIFigure);
            app.GeneradordeSealesLabel.Text = 'Generador de Señales';
            app.sinusoidal = uiaxes(app.UIFigure);
            title(app.sinusoidal, 'Sinusoidal')
            xlabel(app.sinusoidal, 'Tiempo')
            ylabel(app.sinusoidal, 'Amplitud')
            app.triangular = uiaxes(app.UIFigure);
            title(app.triangular, 'Triangular')
            xlabel(app.triangular, 'Tiempo')
            ylabel(app.triangular, 'Amplitud')
            app.cuadrada = uiaxes(app.UIFigure);
            title(app.cuadrada, 'Cuadrada')
            xlabel(app.cuadrada, 'Tiempo')
            ylabel(app.cuadrada, 'Amplitud')
            app.dienteSierra = uiaxes(app.UIFigure);
            title(app.dienteSierra, 'Diente Sierra')
            xlabel(app.dienteSierra, 'Tiempo')
            ylabel(app.dienteSierra, 'Amplitud')
            app.TipodegrficaSinusoidalDropDownLabel = uilabel(app.UIFigure);
            app.TipodegrficaSinusoidalDropDownLabel.Text = 'Tipo de gráfica Sinusoidal';
            app.tipoSinusoidal = uidropdown(app.UIFigure);
            app.tipoSinusoidal.Items = {'Sen(x)', 'Cos(x)', '-Sen(x)', '-Cos(x)'};
            app.tipoSinusoidal.Value = 'Sen(x)';
            app.ConfiguracionesLabel = uilabel(app.UIFigure);
            app.ConfiguracionesLabel.Text = 'Configuraciones';
            app.CantidaddePeriodosEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddePeriodosEditFieldLabel.Text = 'Cantidad de Periodos';
            app.periodos = uieditfield(app.UIFigure, 'numeric');
            app.generar = uibutton(app.UIFigure, 'push');
            app.generar.ButtonPushedFcn = createCallbackFcn(app, @generarButtonPushed, true);
            app.generar.Text = 'Generar gráficas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneradorDeSenales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end