classdef TransmisorAnalogo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TransmisoranlogoLabel     matlab.ui.control.Label
        analoga                   matlab.ui.control.UIAxes
        carrier                   matlab.ui.control.UIAxes
        carrier_2                 matlab.ui.control.UIAxes
        carrier_3                 matlab.ui.control.UIAxes
        carrier_4                 matlab.ui.control.UIAxes
        ConfiguracionesLabel      matlab.ui.control.Label
        generar                   matlab.ui.control.Button
        VoltajecarrierLabel       matlab.ui.control.Label
        voltaje                   matlab.ui.control.NumericEditField
        CantidaddePeridosparaelcarrierLabel  matlab.ui.control.Label
        periodosCarrier           matlab.ui.control.NumericEditField
        VoltajemodulacionesLabel  matlab.ui.control.Label
        voltaje_2                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: generar
        function generarButtonPushed(app, event)
                %carrier
                Ac = app.voltaje.Value;
                fc = 1000e3;
                thetac = 0;
                cpc = app.periodosCarrier.Value;
                
                %xt
                Ax = 2;
                fx = 3400;
                thetax = 0;
                cpx = 2;
                
                %indice modulacion 0 <= m <=
                m = 0.4;
                %indice modulacion M, npm >=2
                nfm = 2;
                %indice modulacion PM, npm >=2
                npm = 2;
                
                %--------PROCESS------
                %carrier
                tc = linspace(0,cpc/fc,500);
                carrier = Ac*sin(2*pi*fc*tc + thetac);
                
                %xt
                tx = linspace(0,cpx/fx,500);
                xt = Ax*sin(2*pi*fx*tx + thetax);
                
                %Modulacion AM
                xam = (1+m*xt).*carrier;
                
                %modulacion FM
                xfm = Ac*sin(2*pi*fc*tc + nfm*xt);
                
                
                %modulacion PM
                xt_diff = Ax*sin(2*pi*fx*tx + (thetax+pi/2));
                xpm = Ac*sin(2*pi*fc*tc + npm*xt_diff);
                
                %--------OUTPUT-------
                figure (1)
                subplot(5,1,1)
                plot(tx,xt)
                title('Datos Analogos x(t)')
                grid on
                
                subplot(5,1,2)
                plot(tc,carrier)
                title('Señal Portadora carier (t)')
                grid on
                
                subplot(5,1,3)
                plot(tc,xam)
                title('Señal AM')
                grid on
                
                subplot(5,1,4)
                plot(tc,xfm)
                title('Señal FM')
                grid on
                
                subplot(5,1,5)
                plot(tc,xpm)
                title('Señal PM')
                grid on
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TransmisoranlogoLabel = uilabel(app.UIFigure);
            app.TransmisoranlogoLabel.Text = 'Transmisor análogo';
            app.analoga = uiaxes(app.UIFigure);
            title(app.analoga, 'Señal análoga')
            xlabel(app.analoga, 'Tiempo')
            ylabel(app.analoga, 'Amplitud')
            app.carrier = uiaxes(app.UIFigure);
            title(app.carrier, 'Señal portadora del carrier')
            xlabel(app.carrier, 'Tiempo')
            ylabel(app.carrier, 'Amplitud')
            app.carrier_2 = uiaxes(app.UIFigure);
            title(app.carrier_2, 'Modulación de la Amplitud AM')
            xlabel(app.carrier_2, 'Tiempo')
            ylabel(app.carrier_2, 'Amplitud')
            app.carrier_3 = uiaxes(app.UIFigure);
            title(app.carrier_3, 'Modulación de la Frecuencia FM')
            xlabel(app.carrier_3, 'Tiempo')
            ylabel(app.carrier_3, 'Amplitud')
            app.carrier_4 = uiaxes(app.UIFigure);
            title(app.carrier_4, 'Modulación del Pulso PM')
            xlabel(app.carrier_4, 'Tiempo')
            ylabel(app.carrier_4, 'Amplitud')
            app.ConfiguracionesLabel = uilabel(app.UIFigure);
            app.ConfiguracionesLabel.Text = 'Configuraciones';
            app.generar = uibutton(app.UIFigure, 'push');
            app.generar.ButtonPushedFcn = createCallbackFcn(app, @generarButtonPushed, true);
            app.generar.Text = {'Generar gráficas'; ''};
            app.VoltajecarrierLabel = uilabel(app.UIFigure);
            app.VoltajecarrierLabel.Text = 'Voltaje carrier';
            app.voltaje = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddePeridosparaelcarrierLabel = uilabel(app.UIFigure);
            app.CantidaddePeridosparaelcarrierLabel.Text = 'Cantidad de Peridos para el carrier';
            app.periodosCarrier = uieditfield(app.UIFigure, 'numeric');
            app.VoltajemodulacionesLabel = uilabel(app.UIFigure);
            app.VoltajemodulacionesLabel.Text = 'Voltaje modulaciones';
            app.voltaje_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransmisorAnalogo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end